classdef WackBotMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ShieldBotScoreBoard5Label  matlab.ui.control.Label
        STOPButton                 matlab.ui.control.Button
        Button_17                  matlab.ui.control.Button
        Button_16                  matlab.ui.control.Button
        Button_18                  matlab.ui.control.Button
        Button_9                   matlab.ui.control.Button
        Button_8                   matlab.ui.control.Button
        Button_7                   matlab.ui.control.Button
        Button_14                  matlab.ui.control.Button
        Button_13                  matlab.ui.control.Button
        Button_15                  matlab.ui.control.Button
        Button_6                   matlab.ui.control.Button
        Button_5                   matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        Button_11                  matlab.ui.control.Button
        Button_10                  matlab.ui.control.Button
        Button_12                  matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        Button_1                   matlab.ui.control.Button
        ResetButton_2              matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        EditField_2                matlab.ui.control.EditField
        EditField                  matlab.ui.control.EditField
        WackBotScoreBoard1Label    matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        modelUR3 % Where I will put the loaded UR5
        modelKUKA
        mole
        eStop
        warningdialog

        qz = [0     0       0       0               0       0   0];

        KUKAq0 =[-0.4 0 0	pi/3   0	    pi/4	    0	    1.3090   pi 0 0 0]
        UR3q0 = [0.2427	4.3808	4.6426	-1.194985	-4.71235274267090	-1.30105403435293	5.95508023803729e-06	5.95508307285278e-06	-0.0270717610752961]; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ax = gca;
            global reset
            reset=false;

            hold on
            axis([-2 2 -2 2 -0.01 2 ]);
%             view(3)
            campos([-0.0045  -10.5324    6.3959])
            camtarget([0.1088   -0.0471    0.7224])
            
%             camva(3.5)
            % Make, save and plot some robots            
            app.modelUR3 = ModifiedUR3;
            app.modelUR3.model.base = transl([-0.6,0,0.574]);
            app.modelUR3.model.animate(app.UR3q0);
            drawnow();

            app.modelKUKA = KUKA(transl([1.1,0,0.5]));
%             app.modelKUKA = KUKA;
%             app.modelKUKA.model.base = transl([0.8,0,0.5]);
            app.modelKUKA.model.animate(app.KUKAq0);
            drawnow();
            
            app.eStop=false;
%             app.reset=false;
          

            surf([-4,-4;4,4] ...
            ,[-4,4;-4,4] ...
            ,[0.01,0.01;0.01,0.01] ...
            ,'CData',imread('floor.jpg') ...
            ,'FaceColor','texturemap');
            
            PlaceObject('tableBrown2.1x1.4x0.5m.ply',[0.25,0,0]);
            
            % PlaceObject('Moles.ply', [0,0,0.5]);
            PlaceObject('Base2.ply', [0,0,0.5]);
            
            moleID(1,1)=PlaceObject('MoleNew.ply', [-0.06, 0.06,    0.515]);
            moleID(2,1)=PlaceObject('MoleNew.ply', [0,    0.06,    0.5]);
            moleID(3,1)=PlaceObject('MoleNew.ply', [0.06,  0.06,    0.5]);
            
            moleID(4,1)=PlaceObject('MoleNew.ply', [-0.06, 0,  0.515]);
            moleID(5,1)=PlaceObject('MoleNew.ply', [0,    0,  0.515]);
            moleID(6,1)=PlaceObject('MoleNew.ply', [0.06,  0,  0.515]);
            
            
            moleID(7,1)=PlaceObject('MoleNew.ply', [-0.06, -0.06,  0.5]);
            moleID(8,1)=PlaceObject('MoleNew.ply', [0,    -0.06,  0.5]);
            moleID(9,1)=PlaceObject('MoleNew.ply', [0.06,  -0.06,  0.5]);

            height=(0.574);
            molePos=[-0.06    0.06     height
                0      0.06     height
                0.06    0.06     height
                -0.06     0       height
                0       0       height
                0.06     0       height
                -0.06   -0.06     height
                0     -0.06      height
                0.06   -0.06      height];

            Status=[0;1;0;0;1;0;1;0;0]
moleID
            app.mole=table(moleID,Status,molePos);


        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
        WackMole(1, app.modelUR3)
        end

        % Callback function
        function RotRButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(5)>-2*pi
                qNew=[qCurrent(1:4),qCurrent(5)-pi/8,qCurrent(6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Callback function
        function BackButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            posCurrent=T(app.modelUR5.model.fkine(qCurrent));
            posNew=posCurrent*transl(0, 0, -0.2);
            qNew=app.modelUR5.model.ikcon(posNew)

            qmatrix = jtraj(qCurrent,qNew,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end
        end

        % Callback function
        function RotLButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(5)<2*pi
                qNew=[qCurrent(1:4),qCurrent(5)+pi/8,qCurrent(6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Callback function
        function ForwardButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            posCurrent=T(app.modelUR5.model.fkine(qCurrent));
            posNew=posCurrent*transl(0, 0, 0.2);
            qNew=app.modelUR5.model.ikcon(posNew)

            qmatrix = jtraj(qCurrent,qNew,20);          
            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end
        end

        % Callback function
        function RotUButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(4)>-2*pi
                qNew=[qCurrent(1:3),qCurrent(4)+pi/8,qCurrent(5:6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Callback function
        function RotDButtonPushed(app, event)
            qCurrent=app.modelUR5.model.getpos();
            if qCurrent(4)>-2*pi
                qNew=[qCurrent(1:3),qCurrent(4)-pi/8,qCurrent(5:6)];
                
                qmatrix = jtraj(qCurrent,qNew,20);          
                for robotStepIndex = 1:size(qmatrix,1)
                    app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                    drawnow;
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            WackMole(2, app.modelUR3);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            WackMole(3, app.modelUR3);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
%             WackMole(5, app.modelUR3,app.mole5);


            height=(0.574)
            mole=[-0.06    0.06     height
                    0      0.06     height
                    0.06    0.06     height
                  -0.06     0       height
                   0       0       height
                   0.06     0       height
                  -0.06   -0.06     height
                   0     -0.06      height
                   0.06   -0.06      height]

            iden=eye(4);
            b=iden*transl(mole(5,:))*trotx(pi);

            UR3q2=app.modelUR3.model.ikcon(b);

            UR3q1=UR3q2;
            
            UR3q1(4)=UR3q1(4)+deg2rad(30);

            app.UR3q0=[0.2427	4.4808	4.6426	-1.294985	-4.71235274267090	-1.30105403435293	5.95508023803729e-06	5.95508307285278e-06	-0.0270717610752961]; % Description

            qmatrix = jtraj(app.UR3q0,UR3q1,100);          
            for robotStepIndex = 1:size(qmatrix,1)
%                 while app.eStop==true
%                     pause(0.1)
%                 end
                app.modelUR3.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
                if app.reset==true
                    break;
                end
            end

            app.reset=false;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            WackMole(4, app.modelUR3,app.mole4);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            botMove(app.mole, app.modelUR3, app.modelKUKA, 6, 1);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            WackMole(7, app.modelUR3);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            WackMole(8, app.modelUR3);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            WackMole(9, app.modelUR3);
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            ShieldBot(app.modelKUKA)
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.eStop=true;

            eStopFunc(app.modelUR3,app.modelKUKA,app.UR3q0,app.KUKAq0)


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            qCurrent=app.modelUR3.model.getpos();
            qmatrix = jtraj(qCurrent,app.UR3q0,100);          
            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR3.model.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end

        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            
            app.ResetButtonPushed()
            app.reset=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.figure1);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WackBotScoreBoard1Label = uilabel(app.GridLayout);
            app.WackBotScoreBoard1Label.Text = {'WackBot ScoreBoard:'; ''; '1'};
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Value = 'WackBot';
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.EditField_2.Value = 'ShieldBot';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ResetButton_2 = uibutton(app.GridLayout, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Icon = fullfile(pathToMLAPP, 'MoleIcon1.PNG');
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'MoleIcon2.PNG');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.Text = '1';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.Text = '2';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.Text = '3';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_15 = uibutton(app.GridLayout, 'push');
            app.Button_15.Text = '4';
            app.Button_13 = uibutton(app.GridLayout, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '5';
            app.Button_14 = uibutton(app.GridLayout, 'push');
            app.Button_14.Text = '6';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_18 = uibutton(app.GridLayout, 'push');
            app.Button_18.Text = '7';
            app.Button_16 = uibutton(app.GridLayout, 'push');
            app.Button_16.Text = '8';
            app.Button_17 = uibutton(app.GridLayout, 'push');
            app.Button_17.Text = '9';
            app.STOPButton = uibutton(app.GridLayout, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ShieldBotScoreBoard5Label = uilabel(app.GridLayout);
            app.ShieldBotScoreBoard5Label.Text = {'ShieldBot ScoreBoard:'; ''; '5'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WackBotMain

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end